classdef EnsayosBeta < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        bt_Icilindro                  matlab.ui.control.Button
        bt_lrectangular               matlab.ui.control.Button
        pb1_salir                     matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        IncertidumbresPanel           matlab.ui.container.Panel
        DinamometroEditFieldLabel     matlab.ui.control.Label
        et_delta_P                    matlab.ui.control.NumericEditField
        VernierEditFieldLabel         matlab.ui.control.Label
        et_delta_l                    matlab.ui.control.NumericEditField
        NLabel                        matlab.ui.control.Label
        mLabel                        matlab.ui.control.Label
        MedicionesPanel               matlab.ui.container.Panel
        CargapuntualLabel             matlab.ui.control.Label
        et_P                          matlab.ui.control.EditField
        NLabel_2                      matlab.ui.control.Label
        DeformacinLabel               matlab.ui.control.Label
        et_Ymax                       matlab.ui.control.EditField
        mLabel_2                      matlab.ui.control.Label
        NmerodedatosEditFieldLabel    matlab.ui.control.Label
        et_n                          matlab.ui.control.NumericEditField
        SizeLabel_2                   matlab.ui.control.Label
        SizeLabel                     matlab.ui.control.Label
        TipodeBarraPanel              matlab.ui.container.Panel
        seleccionarcir                matlab.ui.control.Button
        panel_cilindro                matlab.ui.container.Panel
        RadiorLabel                   matlab.ui.control.Label
        et_r                          matlab.ui.control.NumericEditField
        Longitudiniciall0Label        matlab.ui.control.Label
        et_l0                         matlab.ui.control.NumericEditField
        panel_rectangulo              matlab.ui.container.Panel
        BaseLabel                     matlab.ui.control.Label
        et_b                          matlab.ui.control.NumericEditField
        Longitudiniciall0Label_2      matlab.ui.control.Label
        et_l01                        matlab.ui.control.NumericEditField
        AlturaLabel                   matlab.ui.control.Label
        et_a                          matlab.ui.control.NumericEditField
        AreatransversalcircularLabel  matlab.ui.control.Label
        AreatransversalrectangularLabel  matlab.ui.control.Label
        seleccionarrect               matlab.ui.control.Button
        GraficasPanel                 matlab.ui.container.Panel
        LabelE                        matlab.ui.control.Label
        EcoX                          matlab.ui.control.Label
        Labelr2                       matlab.ui.control.Label
        Comparacion                   matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        Label_4                       matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        Label_6                       matlab.ui.control.Label
        EnsayoPanel                   matlab.ui.container.Panel
        Tension                       matlab.ui.control.CheckBox
        Flexion                       matlab.ui.control.CheckBox
        MdulodeYoungLabel             matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: et_Ymax
        function et_YmaxValueChanged(app, event)
            % value = app.et_Ymax.Value;
                   a=app.et_Ymax.Value;
           global Ymax; 
           Ymax=[]; %Conversion de et_Lf a un vector
            b=erase(a,{'[',']'});
            a=split(b,{' ',','});
            i=1;
            while i<=length(a)
                Ymax=[Ymax,str2double(a(i))];
                i=i+1;
            end
            app.SizeLabel_2.Text=num2str(size(Ymax));
            
        end

        % Value changed function: et_P
        function et_PValueChanged(app, event)
            % value = app.et_P.Value;
               a=app.et_P.Value;
           global P; 
           P=[]; %Conversion de et_Lf a un vector
            b=erase(a,{'[',']'});
            a=split(b,{' ',','});
            i=1;
            while i<=length(a)
                P=[P,str2double(a(i))];
                i=i+1;
            end
            app.SizeLabel.Text=num2str(size(P));
        end

        % Button pushed function: bt_lrectangular
        function bt_lrectangularButtonPushed(app, event)
            
            global P Ymax; 
            EdeT=app.Tension.Value;
            EdeF=app.Flexion.Value;
            if EdeT==1
                
            a=get(app.et_a,'Value'); 
            b=get(app.et_b,'Value');
            l0=get(app.et_l01,'Value');
            delta_l=get(app.et_delta_l,'Value'); %incertidumbre de longitud
                delta_P=get(app.et_delta_P,'Value'); %incertidumbre de carga puntual
               A=a*b;
               delta_A=sqrt((a^2)*(delta_l^2)+(b^2)*(delta_l^2));
                %PT=P';
               %YmaxT=Ymax';
               lf=Ymax-l0;
               sigma=P/A;
               delta_sigma= sqrt((1/A^2)*(delta_P^2)+(P.^2/A^4)*(delta_A^2));
               epsilon=Ymax/l0; 
               delta_epsilon=sqrt((1/l0^2)*(delta_l^2)+(lf.^2/l0^4)*(delta_l^2));
               for i=1:length(P)
       delta_E(:,1)=sqrt((1/epsilon(i)^2)*(delta_sigma(i)^2)+(sigma(i)^2)*(delta_epsilon(i)^2));
              end
       delta_Eprom=mean(delta_E);
       %% Graficas
       errorbar(app.UIAxes,epsilon,sigma,delta_sigma,delta_sigma,delta_epsilon,delta_epsilon,'.');
       p=polyfit(epsilon,sigma,1);
        y=p(1)*epsilon+p(2);
      hold(app.UIAxes)
      plot(app.UIAxes,epsilon,y);
          E=p(1);
        %% Calculo de residuos
        yresid=sigma-y;
        SSresid=sum(yresid.^2);
        SStotal=(length(sigma)-1)*var(sigma);
        rsq=1-SSresid/SStotal;
        app.Labelr2.Text="R^2: "+num2str(rsq);
        %%Modulo de Young;
        app.LabelE.Text="E: "+num2str(E)+"+/-"+ num2str(delta_Eprom) +"[Pa]";
        app.EcoX.Text="Y: " +num2str(E) +"+/-" + num2str(delta_Eprom)+" X"+"+ "+num2str(p(2)); 
        %%Comparación
        xE=[E 101180000 68678000 24910000];
        xT=[1 2 3 4];
        errP=[delta_Eprom 0.0382 0.0382 0.0382];
        errorbar(app.Comparacion,xT,xE',errP,'.');
        app.UIAxes.XLabel.String = '\epsilon';
        app.UIAxes.YLabel.String = '\sigma [Pa]';
        app.UIAxes.Title.String = '\sigma vs \epsilon';
            end
            if EdeF == 1
            a=get(app.et_a,'Value'); 
            b=get(app.et_b,'Value');
                n=get(app.et_n,'Value');
            l0=get(app.et_l01,'Value');
            delta_l=get(app.et_delta_l,'Value'); %incertidumbre de longitud
                delta_lvec=delta_l*ones(n,1); %incertidumbre convertido en vector
          app.Label_4.Text=num2str(size(delta_lvec));
                delta_P=get(app.et_delta_P,'Value'); %incertidumbre de carga puntual
                delta_Pvec=delta_P*ones(n,1); %incertidumbre convertido en vector
           app.Label_5.Text=num2str(size(delta_Pvec));
            J=(a*b^3)/12; %Momento
            delta_J=sqrt((b^6/144)*(delta_l^2)+((a^2*b^4)/16)*(delta_l^2)); %incertidumbre de Momento
               delta_Jvec=delta_J*ones(n,1); %incertidumbre convertido en vector
               app.Label_6.Text=num2str(size(delta_Jvec));
               PT=P';
               YmaxT=Ymax';
        %% Graficas
       errorbar(app.UIAxes,PT,YmaxT,delta_lvec,delta_lvec,delta_Pvec,delta_Pvec,'.');
        p=polyfit(PT,YmaxT,1);
        y=p(1)*PT+p(2);
        hold(app.UIAxes);
        plot(app.UIAxes,PT,y);
        %% Calculo de residuos
        yresid=YmaxT-y;
        SSresid=sum(yresid.^2);
        SStotal=(length(YmaxT)-1)*var(YmaxT);
        rsq=1-SSresid/SStotal;
        m=p(1);
        app.Labelr2.Text="R^2: "+num2str(rsq);
        %%delta m
        p1=(1/PT.^2);
        p2=(delta_lvec.^2);
        p3=(YmaxT.^2);
        p4=(delta_Pvec.^2);
        p5=p1*p2;
        p6=(p3'*p4);
        delta_m=p5+p6;
        %%Modulo de Young;
        E=l0^3/(192*J*m);
        delta_E=sqrt((l0^6/(36864*J^2*YmaxT.^2))*(delta_Pvec.^2)+((PT.^2*l0^4)/(4096*J^2*YmaxT.^2))*(delta_lvec.^2)+((PT.^2*l0^6)/(36864*J^4*YmaxT.^2))*(delta_Jvec.^2)+((PT.^2*l0^6)/(36864*J^2*YmaxT.^4))*delta_lvec.^2);
        delta_Eprom=mean(delta_E);
        app.LabelE.Text="E: "+num2str(E)+"+/-"+ num2str(delta_Eprom) +"[Pa]";
        app.EcoX.Text="Y: " +num2str(m) +"+/-" + num2str(delta_m)+" X"+"+ "+num2str(p(2)); 
         %%Comparación
        xE=[E 101180000 68678000 24910000];
        xT=[1 2 3 4];
        errP=[delta_m 0.0382 0.0382 0.0382];
        errorbar(app.Comparacion,xT,xE',errP,'.')
        app.UIAxes.XLabel.String = 'P';
        app.UIAxes.YLabel.String = 'Y_{max}';
        app.UIAxes.Title.String = 'Y_{max} vs P';
            end
        end

        % Callback function
        function bt_GomaButtonPushed(app, event)
            
        end

        % Button pushed function: bt_Icilindro
        function bt_IcilindroButtonPushed(app, event)
          
            global P Ymax; %Calculo de epsilon y sigma. Graficos y ajuste lineal
            EdeT=app.Tension.Value;
            EdeF=app.Flexion.Value;
            if EdeT==1
                 r=get(app.et_r,'Value');
            l0=get(app.et_l0,'Value');
            delta_l=get(app.et_delta_l,'Value'); %incertidumbre de longitud
                delta_P=get(app.et_delta_P,'Value'); %incertidumbre de carga puntual
               A=pi*r^2;
               delta_A=(2*pi*r)*(delta_l);
                PT=P';
               YmaxT=Ymax';
               lf=YmaxT-l0;
               sigma=PT/A;
               delta_sigma= sqrt((1/A^2)*(delta_P^2)+((PT.^2)/A^4)*(delta_A^2));
               epsilon=YmaxT./l0; 
               delta_epsilon=sqrt((1/l0^2)*(delta_l^2)+(lf.^2/l0^4)*(delta_l^2));
               for i=1:length(PT)
       delta_E(:,1)=sqrt((1/epsilon(i)^2)*(delta_sigma(i)^2)+(sigma(i)^2)*(delta_epsilon(i)^2));
               end
        app.UIAxes.XLabel.String = '\epsilon';
        app.UIAxes.YLabel.String = '\sigma [Pa]';
        app.UIAxes.Title.String = '\sigma vs \epsilon';
       delta_Eprom=mean(delta_E);
       %% Graficas
       errorbar(app.UIAxes,epsilon,sigma,delta_sigma,delta_sigma,delta_epsilon,delta_epsilon,'.');
       p=polyfit(epsilon,sigma,1);
        y=p(1)*epsilon+p(2);
      hold(app.UIAxes)
      plot(app.UIAxes,epsilon,y);
          E=p(1);
        %% Calculo de residuos
        yresid=sigma-y;
        SSresid=sum(yresid.^2);
        SStotal=(length(sigma)-1)*var(sigma);
        rsq=1-SSresid/SStotal;
        app.Labelr2.Text="R^2: "+num2str(rsq);
        %%Modulo de Young;
        app.LabelE.Text="E: "+num2str(E)+"+/-"+ num2str(delta_Eprom) +"[Pa]";
        app.EcoX.Text="Y: " +num2str(E) +"+/-" + num2str(delta_Eprom)+" X"+"+ "+num2str(p(2)); 
         %%Comparación
        xE=[E 101180000 68678000 24910000];
        xT=[1 2 3 4];
        errP=[delta_Eprom 0.0382 0.0382 0.0382];
        errorbar(app.Comparacion,xT,xE',errP,'.')
            end
        if EdeF==1
            r=get(app.et_r,'Value');
            n=get(app.et_n,'Value');
            l0=get(app.et_l0,'Value');
            delta_l=get(app.et_delta_l,'Value'); %incertidumbre de longitud
                delta_lvec=delta_l*ones(n,1); %incertidumbre convertido en vector
          app.Label_4.Text=num2str(size(delta_lvec));
                delta_P=get(app.et_delta_P,'Value'); %incertidumbre de carga puntual
                delta_Pvec=delta_P*ones(n,1); %incertidumbre convertido en vector
           app.Label_5.Text=num2str(size(delta_Pvec));
            J=pi*r^4/4; %Momento de inercia en metros
                delta_J=sqrt((r^6*pi^2)*(delta_l^2));
               delta_Jvec=delta_J*ones(n,1); %incertidumbre convertido en vector
               app.Label_6.Text=num2str(size(delta_Jvec));
               PT=P';
               YmaxT=Ymax';
        %% Graficas
       errorbar(app.UIAxes,PT,YmaxT,delta_lvec,delta_lvec,delta_Pvec,delta_Pvec,'.');
        p=polyfit(PT,YmaxT,1);
        y=p(1)*PT+p(2);
        hold(app.UIAxes);
        plot(app.UIAxes,PT,y);
        %% Calculo de residuos
        yresid=YmaxT-y;
        SSresid=sum(yresid.^2);
        SStotal=(length(YmaxT)-1)*var(YmaxT);
        rsq=1-SSresid/SStotal;
        m=p(1);
        app.Labelr2.Text="R^2: "+num2str(rsq);
        %%delta m
        p1=(1/PT.^2);
        p2=(delta_lvec.^2);
        p3=(YmaxT.^2);
        p4=(delta_Pvec.^2);
        p5=p1*p2;
        p6=(p3'*p4);
        delta_m=p5+p6;
        %%Modulo de Young
        E=l0^3/(192*J*m);
        delta_E=sqrt((l0^6/(36864*J^2*YmaxT.^2))*(delta_Pvec.^2)+((PT.^2*l0^4)/(4096*J^2*YmaxT.^2))*(delta_lvec.^2)+((PT.^2*l0^6)/(36864*J^4*YmaxT.^2))*(delta_Jvec.^2)+((PT.^2*l0^6)/(36864*J^2*YmaxT.^4))*delta_lvec.^2);
        delta_Eprom=mean(delta_E);
        app.LabelE.Text="E: "+num2str(E)+"+/-"+ num2str(delta_Eprom) +" [Pa]";
        app.EcoX.Text="Y: " +num2str(m) +"+/-" + num2str(delta_m)+"X"+"+"+num2str(p(2)); 
         %%Comparación
        xE=[E 101180000 68678000 24910000];
        xT=[1 2 3 4];
        errP=[delta_m 0.0382 0.0382 0.0382];
        errorbar(app.Comparacion,xT,xE',errP,'.');
        app.UIAxes.XLabel.String = 'P';
        app.UIAxes.YLabel.String = 'Y_{max}';
        app.UIAxes.Title.String = 'Y_{max} vs P';
        end
        end

        % Button pushed function: pb1_salir
        function pb1_salirButtonPushed(app, event)
            close("all","force")
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.UIAxes.cla;
            clearvars -global
            
        end

        % Value changed function: et_delta_P
        function et_delta_PValueChanged(app, event)
           % value = app.et_delta_P.Value;
            
        end

        % Size changed function: panel_cilindro
        function panel_cilindroSizeChanged(app, event)

            app.panel_cilindro.Visible = 'off';
        end

        % Button pushed function: seleccionarcir
        function seleccionarcirButtonPushed(app, event)
    app.panel_cilindro.Visible = 'on';
    app.bt_Icilindro.Visible = 'on';
    app.panel_rectangulo.Visible = 'off';
    app.bt_lrectangular.Visible = 'off';
        end

        % Button pushed function: seleccionarrect
        function seleccionarrectButtonPushed(app, event)
                app.panel_rectangulo.Visible = 'on';
                app.bt_lrectangular.Visible = 'on';
                app.panel_cilindro.Visible = 'off';
                app.bt_Icilindro.Visible = 'off';
        end

        % Value changed function: Flexion
        function FlexionValueChanged(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.bt_Icilindro = uibutton(app.UIFigure, 'push');
            app.bt_Icilindro.ButtonPushedFcn = createCallbackFcn(app, @bt_IcilindroButtonPushed, true);
            app.bt_Icilindro.Tag = 'bt_Icilindro';
            app.bt_Icilindro.Visible = 'off';
            app.bt_Icilindro.Text = 'Iniciar';
            app.bt_lrectangular = uibutton(app.UIFigure, 'push');
            app.bt_lrectangular.ButtonPushedFcn = createCallbackFcn(app, @bt_lrectangularButtonPushed, true);
            app.bt_lrectangular.Tag = 'bt_lrectangular';
            app.bt_lrectangular.Visible = 'off';
            app.bt_lrectangular.Text = 'Iniciar';
            app.pb1_salir = uibutton(app.UIFigure, 'push');
            app.pb1_salir.ButtonPushedFcn = createCallbackFcn(app, @pb1_salirButtonPushed, true);
            app.pb1_salir.Tag = 'pb1_salir';
            app.pb1_salir.Text = 'Salir';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Tag = 'ResetButton';
            app.ResetButton.Text = 'Reset';
            app.IncertidumbresPanel = uipanel(app.UIFigure);
            app.IncertidumbresPanel.Title = 'Incertidumbres';
            app.DinamometroEditFieldLabel = uilabel(app.IncertidumbresPanel);
            app.DinamometroEditFieldLabel.Text = 'Dinamometro';
            app.et_delta_P = uieditfield(app.IncertidumbresPanel, 'numeric');
            app.et_delta_P.ValueChangedFcn = createCallbackFcn(app, @et_delta_PValueChanged, true);
            app.et_delta_P.Tag = 'et_Delta_P';
            app.VernierEditFieldLabel = uilabel(app.IncertidumbresPanel);
            app.VernierEditFieldLabel.Text = 'Vernier';
            app.et_delta_l = uieditfield(app.IncertidumbresPanel, 'numeric');
            app.et_delta_l.Tag = 'et_delta_l';
            app.NLabel = uilabel(app.IncertidumbresPanel);
            app.NLabel.Text = '[N]';
            app.mLabel = uilabel(app.IncertidumbresPanel);
            app.mLabel.Text = '[m]';
            app.MedicionesPanel = uipanel(app.UIFigure);
            app.MedicionesPanel.Title = 'Mediciones';
            app.CargapuntualLabel = uilabel(app.MedicionesPanel);
            app.CargapuntualLabel.Tag = 'et_P';
            app.CargapuntualLabel.Text = 'Carga puntual:';
            app.et_P = uieditfield(app.MedicionesPanel, 'text');
            app.et_P.ValueChangedFcn = createCallbackFcn(app, @et_PValueChanged, true);
            app.et_P.Tag = 'et_P';
            app.NLabel_2 = uilabel(app.MedicionesPanel);
            app.NLabel_2.Text = '[N]';
            app.DeformacinLabel = uilabel(app.MedicionesPanel);
            app.DeformacinLabel.Tag = 'et_P';
            app.DeformacinLabel.Text = 'Deformación:';
            app.et_Ymax = uieditfield(app.MedicionesPanel, 'text');
            app.et_Ymax.ValueChangedFcn = createCallbackFcn(app, @et_YmaxValueChanged, true);
            app.et_Ymax.Tag = 'et_Ymax';
            app.mLabel_2 = uilabel(app.MedicionesPanel);
            app.mLabel_2.Text = '[m]';
            app.NmerodedatosEditFieldLabel = uilabel(app.MedicionesPanel);
            app.NmerodedatosEditFieldLabel.Text = 'Número de datos';
            app.et_n = uieditfield(app.MedicionesPanel, 'numeric');
            app.SizeLabel_2 = uilabel(app.MedicionesPanel);
            app.SizeLabel_2.Text = 'Size';
            app.SizeLabel = uilabel(app.MedicionesPanel);
            app.SizeLabel.Text = 'Size';
            app.TipodeBarraPanel = uipanel(app.UIFigure);
            app.TipodeBarraPanel.Title = 'Tipo de Barra';
            app.seleccionarcir = uibutton(app.TipodeBarraPanel, 'push');
            app.seleccionarcir.ButtonPushedFcn = createCallbackFcn(app, @seleccionarcirButtonPushed, true);
            app.seleccionarcir.Tag = 'bt_seleccionar';
            app.seleccionarcir.Text = 'Seleccionar';
            app.panel_cilindro = uipanel(app.TipodeBarraPanel);
            app.panel_cilindro.Title = 'Datos de la barra cilindrica';
            app.panel_cilindro.Visible = 'off';
            app.panel_cilindro.SizeChangedFcn = createCallbackFcn(app, @panel_cilindroSizeChanged, true);
            app.panel_cilindro.Tag = 'panel_cilindro';
            app.RadiorLabel = uilabel(app.panel_cilindro);
            app.RadiorLabel.Text = 'Radio (r):';
            app.et_r = uieditfield(app.panel_cilindro, 'numeric');
            app.et_r.Tag = 'et_r';
            app.Longitudiniciall0Label = uilabel(app.panel_cilindro);
            app.Longitudiniciall0Label.Text = 'Longitud inicial(l0):';
            app.et_l0 = uieditfield(app.panel_cilindro, 'numeric');
            app.et_l0.Tag = 'et_l0';
            app.panel_rectangulo = uipanel(app.TipodeBarraPanel);
            app.panel_rectangulo.Title = 'Datos de la barra rectangular';
            app.panel_rectangulo.Visible = 'off';
            app.panel_rectangulo.Tag = 'panel_cilindro';
            app.BaseLabel = uilabel(app.panel_rectangulo);
            app.BaseLabel.Text = 'Base:';
            app.et_b = uieditfield(app.panel_rectangulo, 'numeric');
            app.et_b.Tag = 'et_b';
            app.Longitudiniciall0Label_2 = uilabel(app.panel_rectangulo);
            app.Longitudiniciall0Label_2.Text = 'Longitud inicial(l0)';
            app.et_l01 = uieditfield(app.panel_rectangulo, 'numeric');
            app.et_l01.Tag = 'et_a';
            app.AlturaLabel = uilabel(app.panel_rectangulo);
            app.AlturaLabel.Text = 'Altura:';
            app.et_a = uieditfield(app.panel_rectangulo, 'numeric');
            app.et_a.Tag = 'et_l01';
            app.AreatransversalcircularLabel = uilabel(app.TipodeBarraPanel);
            app.AreatransversalcircularLabel.Text = 'Area transversal circular';
            app.AreatransversalrectangularLabel = uilabel(app.TipodeBarraPanel);
            app.AreatransversalrectangularLabel.Text = 'Area transversal rectangular';
            app.seleccionarrect = uibutton(app.TipodeBarraPanel, 'push');
            app.seleccionarrect.ButtonPushedFcn = createCallbackFcn(app, @seleccionarrectButtonPushed, true);
            app.seleccionarrect.Text = 'Seleccionar';
            app.GraficasPanel = uipanel(app.UIFigure);
            app.GraficasPanel.Title = 'Graficas';
            app.LabelE = uilabel(app.GraficasPanel);
            app.LabelE.Tag = 'LabelE';
            app.LabelE.Text = '';
            app.EcoX = uilabel(app.GraficasPanel);
            app.EcoX.Text = '';
            app.Labelr2 = uilabel(app.GraficasPanel);
            app.Labelr2.Tag = 'Labelr2';
            app.Labelr2.Text = '';
            app.Comparacion = uiaxes(app.GraficasPanel);
            title(app.Comparacion, 'Comparacíon')
            xlabel(app.Comparacion, '')
            ylabel(app.Comparacion, 'E [Pa]')
            app.Comparacion.XLim = [0 5];
            app.Comparacion.XTickLabel = {'0'; 'E_{actual}'; 'E_{Ventral}'; 'E_{Dorsal}'; 'E_{Palma}'; '5'};
            app.UIAxes = uiaxes(app.GraficasPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Visible = 'off';
            app.Label_4.Text = 'Label3';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Visible = 'off';
            app.Label_5.Text = 'Label4';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Visible = 'off';
            app.Label_6.Text = 'Label5';
            app.EnsayoPanel = uipanel(app.UIFigure);
            app.EnsayoPanel.Title = 'Ensayo';
            app.Tension = uicheckbox(app.EnsayoPanel);
            app.Tension.Text = 'Tensión';
            app.Flexion = uicheckbox(app.EnsayoPanel);
            app.Flexion.ValueChangedFcn = createCallbackFcn(app, @FlexionValueChanged, true);
            app.Flexion.Text = 'Flexión';
            app.MdulodeYoungLabel = uilabel(app.UIFigure);
            app.MdulodeYoungLabel.Text = 'Módulo de Young';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EnsayosBeta

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end